classdef PUMA_260 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RESETButton                matlab.ui.control.Button
        EXITButton                 matlab.ui.control.Button
        ThetavaluesafterinverseKinematicsLabel  matlab.ui.control.Label
        UITable3                   matlab.ui.control.Table
        TransformationMatrixLabel  matlab.ui.control.Label
        UITable2                   matlab.ui.control.Table
        DHParametersLabel          matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        Theta_4EditField           matlab.ui.control.NumericEditField
        Theta_4EditFieldLabel      matlab.ui.control.Label
        ZEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        XEditFieldLabel            matlab.ui.control.Label
        InverseButton              matlab.ui.control.Button
        ForwardButton              matlab.ui.control.Button
        Theta_6EditField           matlab.ui.control.NumericEditField
        Theta_6EditFieldLabel      matlab.ui.control.Label
        Theta_5EditField           matlab.ui.control.NumericEditField
        Theta_5EditFieldLabel      matlab.ui.control.Label
        Theta_3EditField           matlab.ui.control.NumericEditField
        Theta_3EditFieldLabel      matlab.ui.control.Label
        Theta_2EditField           matlab.ui.control.NumericEditField
        Theta_2EditFieldLabel      matlab.ui.control.Label
        Theta_1EditField           matlab.ui.control.NumericEditField
        Theta_1EditFieldLabel      matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            THETA_1 = app.Theta_1EditField.Value;
            THETA_2 = app.Theta_2EditField.Value;
            THETA_3 = app.Theta_3EditField.Value;
            THETA_4 = app.Theta_4EditField.Value;
            THETA_5 = app.Theta_5EditField.Value;
            THETA_6 = app.Theta_6EditField.Value;
            
            theta_1 = THETA_1*pi/180;
            theta_2 = THETA_2*pi/180;
            theta_3 = THETA_3*pi/180;
            theta_4 = THETA_4*pi/180;
            theta_5 = THETA_5*pi/180;
            theta_6 = THETA_6*pi/180;
            
            L_1 = 13;
            L_2 = 8;
            L_3 = 3;
            L_4 = 8;
            L_5 = 2;
            L_6 = 3;
            
DH = [theta_1 13  0 -1.5708;
      theta_2 0 8 0;
      theta_3 -3 0 1.5708;
      theta_4 8 0 -1.5708;
      theta_5 0 0 1.5708;
      theta_6 5 0 0]
            if (THETA_1 > 110 | THETA_1 < -180)
                 f = errordlg("Given angles are invalid for your Robot")
            elseif (THETA_2 > 75 | THETA_2 < -240)
                 f = errordlg("Given angles are invalid for your Robot")
            elseif (THETA_3 > 100| THETA_3 < -210)
                 f = errordlg("Given angles are invalid for your Robot")
             elseif (THETA_4 > 120 | THETA_4 < -460)
                 f = errordlg("Given angles are invalid for your Robot")
            elseif (THETA_5 > 160 | THETA_5 < -30)
                 f = errordlg("Given angles are invalid for your Robot")
             elseif (THETA_6 > 295 | THETA_6 < -215)
                 f = errordlg("Given angles are invalid for your Robot")
            else
            
            
L(1) = Link([theta_1 L_1 0 -pi/2]);
L(2) = Link([theta_2 0 L_2 0]);
L(3) = Link([theta_3+pi/2  -L_3 0 pi/2]);
L(4) = Link([theta_4 L_4 0 -pi/2]);
L(5) = Link([theta_5+pi/2  0 0 pi/2]);
L(6) = Link([theta_6  L_5+L_6 0 0]);

Puma_260 = SerialLink(L)
Puma_260.name = 'PUMA 260';

A1 = DH_A(0,  -pi/2,   L_1, theta_1);
A2 = DH_A(L_2,     0,  0, theta_2);
A3 = DH_A(0, pi/2,  -L_3, theta_3 );
A4 = DH_A(0,  -pi/2,   L_4, theta_4);
A5 = DH_A(0, pi/2,   0, theta_5 );
A6 = DH_A(0,     0,    L_5+L_6, theta_6);

T = A1*A2*A3*A4*A5*A6
Trans = T;
H = T(1:3,4)
x =round(H(1),3)
y =round(H(2),3)
z =round(H(3),3)
app.XEditField.Value = x;
app.YEditField.Value = y;
app.ZEditField.Value = z;

theta_initial = 0;

Th_path{1}=linspace(theta_initial,theta_1,20);
Th_path{2}=linspace(theta_initial,theta_2,20);
Th_path{3}=linspace(theta_initial,theta_3+pi/2,20);
Th_path{4}=linspace(theta_initial,theta_4,20);
Th_path{5}=linspace(theta_initial,theta_5-pi/2,20);
Th_path{6}=linspace(theta_initial,theta_6,20);


for i=1:20
Puma_260.plot([Th_path{1}(i) Th_path{2}(i) Th_path{3}(i) Th_path{4}(i) Th_path{5}(i) Th_path{6}(i)]); 
end


app.UITable.Data = DH
app.UITable2.Data = Trans
            end
function A=DH_A(a,alpha,d,theta)

A=[cos(theta) -sin(theta)*cos(alpha) sin(theta)*sin(alpha) a*cos(theta);...
  sin(theta) cos(theta)*cos(alpha) -cos(theta)*sin(alpha) a*sin(theta);...
  0 sin(alpha) cos(alpha) d;...
  0 0 0 1];
end
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
                     THETA_1 = app.Theta_1EditField.Value;
            THETA_2 = app.Theta_2EditField.Value;
            THETA_3 = app.Theta_3EditField.Value;
            THETA_4 = app.Theta_4EditField.Value;
            THETA_5 = app.Theta_5EditField.Value;
            THETA_6 = app.Theta_6EditField.Value;
            
            theta_1 = THETA_1*pi/180;
            theta_2 = THETA_2*pi/180;
            theta_3 = THETA_3*pi/180;
            theta_4 = THETA_4*pi/180;
            theta_5 = THETA_5*pi/180;
            theta_6 = THETA_6*pi/180;
            
            L_1 = 13;
            L_2 = 8;
            L_3 = 3;
            L_4 = 8;
            L_5 = 2;
            L_6 = 3;
            
L(1) = Link([theta_1 L_1 0 -pi/2]);
L(2) = Link([theta_2 0 L_2 0]);
L(3) = Link([theta_3  -L_3 0 pi/2]);
L(4) = Link([theta_4 L_4 0 -pi/2]);
L(5) = Link([theta_5  0 0 pi/2]);
L(6) = Link([theta_6  L_5+L_6 0 0]);

Puma_260 = SerialLink(L)
Puma_260.name = 'PUMA 260';
A1 = DH_A(0,  -pi/2,   L_1, theta_1);
A2 = DH_A(L_2,     0,  0, theta_2);
A3 = DH_A(0, pi/2,  -L_3, theta_3 );
A4 = DH_A(0,  -pi/2,   L_4, theta_4);
A5 = DH_A(0, pi/2,   0, theta_5 );
A6 = DH_A(0,     0,    L_5+L_6, theta_6);

T = A1*A2*A3*A4*A5*A6
q = Puma_260.ikine(T)
Puma_260.plot(q)
app.UITable3.Data = q
       
function A=DH_A(a,alpha,d,theta)

A=[cos(theta) -sin(theta)*cos(alpha) sin(theta)*sin(alpha) a*cos(theta);...
  sin(theta) cos(theta)*cos(alpha) -cos(theta)*sin(alpha) a*sin(theta);...
  0 sin(alpha) cos(alpha) d;...
  0 0 0 1];
end

        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
             closereq();
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.Theta_1EditField.Value = 0;
            app.Theta_2EditField.Value = 0;
            app.Theta_3EditField.Value = 0;
            app.Theta_4EditField.Value = 0;
            app.Theta_5EditField.Value = 0;
            app.Theta_6EditField.Value = 0;
            app.XEditField.Value = 0;
            app.YEditField.Value = 0;
            app.ZEditField.Value = 0;
            app.UITable.Data = 0;
            app.UITable2.Data = 0;
            app.UITable3.Data = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Theta_1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta_1EditFieldLabel.Text = 'Theta_1';
            app.Theta_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta_2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta_2EditFieldLabel.Text = 'Theta_2';
            app.Theta_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta_3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta_3EditFieldLabel.Text = 'Theta_3';
            app.Theta_3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta_5EditFieldLabel = uilabel(app.UIFigure);
            app.Theta_5EditFieldLabel.Text = 'Theta_5';
            app.Theta_5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta_6EditFieldLabel = uilabel(app.UIFigure);
            app.Theta_6EditFieldLabel.Text = 'Theta_6';
            app.Theta_6EditField = uieditfield(app.UIFigure, 'numeric');
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.InverseButton = uibutton(app.UIFigure, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta_4EditFieldLabel = uilabel(app.UIFigure);
            app.Theta_4EditFieldLabel.Text = 'Theta_4';
            app.Theta_4EditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'theta'; 'd'; 'a'; 'alpha'};
            app.UITable.RowName = {};
            app.DHParametersLabel = uilabel(app.UIFigure);
            app.DHParametersLabel.Text = 'DH Parameters';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.TransformationMatrixLabel = uilabel(app.UIFigure);
            app.TransformationMatrixLabel.Text = 'Transformation Matrix';
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = {'Theta_1'; 'Theta_2'; 'Theta_3'; 'Theta_4'; 'Theta_5'; 'Theta_6'};
            app.UITable3.RowName = {};
            app.ThetavaluesafterinverseKinematicsLabel = uilabel(app.UIFigure);
            app.ThetavaluesafterinverseKinematicsLabel.Text = 'Theta values after inverse Kinematics';
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PUMA_260

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end